classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        IfTestTrainselecetedListBox  matlab.ui.control.ListBox
        IfTestTrainselecetedListBoxLabel  matlab.ui.control.Label
        OptionListBox                matlab.ui.control.ListBox
        OptionListBoxLabel           matlab.ui.control.Label
        ActionListBox                matlab.ui.control.ListBox
        ActionListBoxLabel           matlab.ui.control.Label
        PatienteListBox              matlab.ui.control.ListBox
        PatienteListBoxLabel         matlab.ui.control.Label
        SpecificityPanel             matlab.ui.container.Panel
        Label1_3                     matlab.ui.control.Label
        SensitivityPanel             matlab.ui.container.Panel
        Label1                       matlab.ui.control.Label
        IfTestselcetedListBox        matlab.ui.control.ListBox
        IfTestselcetedListBoxLabel   matlab.ui.control.Label
        ConfirmButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfirmButton
        function run(app, event)
            [sense, spec]=run(app.PatienteListBox.Value,app.ActionListBox.Value ...
                ,app.OptionListBox.Value,app.IfTestselcetedListBox.Value,app.IfTestTrainselecetedListBox.Value);
            app.Label1.Text=string(sense);
            app.Label1_3.Text=string(spec);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.ConfirmButton.Text = 'Confirm';
            app.IfTestselcetedListBoxLabel = uilabel(app.UIFigure);
            app.IfTestselcetedListBoxLabel.Text = 'If Test selceted';
            app.IfTestselcetedListBox = uilistbox(app.UIFigure);
            app.IfTestselcetedListBox.Items = {'CNN', 'LSTM', 'NonDynamic', 'Dynamic'};
            app.IfTestselcetedListBox.Value = 'CNN';
            app.SensitivityPanel = uipanel(app.UIFigure);
            app.SensitivityPanel.Title = 'Sensitivity';
            app.Label1 = uilabel(app.SensitivityPanel);
            app.Label1.Text = '0.0';
            app.SpecificityPanel = uipanel(app.UIFigure);
            app.SpecificityPanel.Title = 'Specificity';
            app.Label1_3 = uilabel(app.SpecificityPanel);
            app.Label1_3.Text = '0.0';
            app.PatienteListBoxLabel = uilabel(app.UIFigure);
            app.PatienteListBoxLabel.Text = 'Patiente';
            app.PatienteListBox = uilistbox(app.UIFigure);
            app.PatienteListBox.Items = {'44202', '63502'};
            app.PatienteListBox.Value = '44202';
            app.ActionListBoxLabel = uilabel(app.UIFigure);
            app.ActionListBoxLabel.Text = 'Action';
            app.ActionListBox = uilistbox(app.UIFigure);
            app.ActionListBox.Items = {'Detect', 'Predict'};
            app.ActionListBox.Value = 'Detect';
            app.OptionListBoxLabel = uilabel(app.UIFigure);
            app.OptionListBoxLabel.Text = 'Option';
            app.OptionListBox = uilistbox(app.UIFigure);
            app.OptionListBox.Items = {'Test', 'Train&Test'};
            app.OptionListBox.Value = 'Test';
            app.IfTestTrainselecetedListBoxLabel = uilabel(app.UIFigure);
            app.IfTestTrainselecetedListBoxLabel.Text = 'If Test&Train seleceted';
            app.IfTestTrainselecetedListBox = uilistbox(app.UIFigure);
            app.IfTestTrainselecetedListBox.Items = {'CNN_(5,20)', 'CNN_([6,4],32)', 'LSTM_HU50', 'LSTM_HU125', 'ND_20_10', 'ND_10', 'DNN_1:3_20_10', 'DNN_1:2_20_10'};
            app.IfTestTrainselecetedListBox.Value = 'CNN_(5,20)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end